classdef ImportLines < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        TextBox   matlab.ui.control.EditField
        Cancel    matlab.ui.control.Button
        Confirm   matlab.ui.control.Button
        Label     matlab.ui.control.Label
        Button    matlab.ui.control.Button
    end

    
    properties (Access = public)
        callapp % mainapp
        
    end
    
    properties (Access = private)
        D % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, D)
            app.callapp=mainapp;  
            app.D=[D];
            if D==2
                app.EditField_3.Enable="off";
                app.EditField_6.Enable="off";
                app.EditField_7.Enable="off";
                app.EditField_8.Enable="off";
                app.EditField_9.Enable="off";

            elseif D==3
                app.EditField_3.Enable="on";
                app.EditField_6.Enable="on";
                app.EditField_7.Enable="on";
                app.EditField_8.Enable="on";
                app.EditField_9.Enable="on";
            end                 
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            folder = uigetdir();       
            app.TextBox.Value = folder;            
        end

        % Button pushed function: Confirm
        function ConfirmPushed(app, event)
            folder=app.TextBox.Value;
            updateLines(app.callapp,folder);  
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            delete(app);
        end

        % Callback function: not associated with a component
        function Button_2Pushed(app, event)
            [file,path] = uigetfile({'*.txt'; '*.csv';'*.xlsx';'*.xls'}, '选择要导入的文件');
            app.TextBox_2.Value = [path,file];
        end

        % Button pushed function: Cancel
        function CancelButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '浏览';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '选择文件夹';
            app.Confirm = uibutton(app.UIFigure, 'push');
            app.Confirm.ButtonPushedFcn = createCallbackFcn(app, @ConfirmPushed, true);
            app.Confirm.Text = '确定';
            app.Cancel = uibutton(app.UIFigure, 'push');
            app.Cancel.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.Cancel.Text = '取消';
            app.TextBox = uieditfield(app.UIFigure, 'text');
            app.TextBox.Value = 'E:\KY\xhly\LayerLines';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImportLines(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end