classdef ImportLines_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        Button     matlab.ui.control.Button
        Label      matlab.ui.control.Label
        Confirm    matlab.ui.control.Button
        Label_2    matlab.ui.control.Label
        Cancel     matlab.ui.control.Button
        Button_2   matlab.ui.control.Button
        TextBox_2  matlab.ui.control.EditField
        TextBox    matlab.ui.control.EditField
    end

    
    properties (Access = public)
        callapp % mainapp
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.callapp=mainapp;                     
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            folder = uigetdir();       
            app.TextBox.Value = folder;            
        end

        % Button pushed function: Confirm
        function ConfirmPushed(app, event)
            folder=app.TextBox.Value;
            filepath=app.TextBox_2.Value;
            E=[1,0;0,1];
            updateLines(app.callapp,folder,filepath,E);  
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            delete(app);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            [file,path] = uigetfile({'*.txt'; '*.csv';'*.xlsx';'*.xls'}, '选择要导入的文件');
            app.TextBox_2.Value = [path,file];
        end

        % Button pushed function: Cancel
        function CancelButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '浏览';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '选择文件夹';
            app.Confirm = uibutton(app.UIFigure, 'push');
            app.Confirm.ButtonPushedFcn = createCallbackFcn(app, @ConfirmPushed, true);
            app.Confirm.Text = '确定';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '属性值文件';
            app.Cancel = uibutton(app.UIFigure, 'push');
            app.Cancel.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.Cancel.Text = '取消';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '浏览';
            app.TextBox_2 = uieditfield(app.UIFigure, 'text');
            app.TextBox_2.Value = 'E:\RZ_matlab\data\2Dtest\m2属性值表.xlsx';
            app.TextBox = uieditfield(app.UIFigure, 'text');
            app.TextBox.Value = 'E:\RZ_matlab\data\2Dtest\lines_m2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImportLines_2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end