classdef SelectSolution < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Cancel           matlab.ui.control.Button
        Confirm          matlab.ui.control.Button
        ZEditField       matlab.ui.control.NumericEditField
        ZEditFieldLabel  matlab.ui.control.Label
        YEditField       matlab.ui.control.NumericEditField
        YEditFieldLabel  matlab.ui.control.Label
        XEditField       matlab.ui.control.NumericEditField
        XEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        callingApp% Description
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, X, Y, Z, D)
            app.callingApp=mainapp;
            app.XEditField.Value=X;
            app.YEditField.Value=Y;
            app.ZEditField.Value=Z;
            if D==2
                app.ZEditField.Editable='off';
            elseif D==3
                app.ZEditField.Editable='on';
            end
        end

        % Button pushed function: Confirm
        function ConfirmButtonPushed(app, event)
            bool=SetSolution(app.callingApp,app.XEditField.Value,app.YEditField.Value,app.ZEditField.Value);
            if(~bool)
                fig = uifigure;
                uialert(fig,'分辨率设置非法，请检查数值设置','数据错误');
                return;           
            end          
            setSignal(app.callingApp,true);
            delete(app);
        end

        % Button pushed function: Cancel
        function CancelButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditField.Value = 1;
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField.Value = 1;
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField.Value = 1;
            app.Confirm = uibutton(app.UIFigure, 'push');
            app.Confirm.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.Confirm.Text = '确认';
            app.Cancel = uibutton(app.UIFigure, 'push');
            app.Cancel.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.Cancel.Text = '取消';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SelectSolution(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end