classdef SetBoundary < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        BottomEditField       matlab.ui.control.NumericEditField
        BottomEditFieldLabel  matlab.ui.control.Label
        TopEditField          matlab.ui.control.NumericEditField
        TopEditFieldLabel     matlab.ui.control.Label
        TextBox               matlab.ui.control.EditField
        Cancel                matlab.ui.control.Button
        Confirm               matlab.ui.control.Button
        Label                 matlab.ui.control.Label
        Button                matlab.ui.control.Button
    end

    
    properties (Access = public)
        callapp % mainapp
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.callapp=mainapp;                     
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            filepath = uigetfile();       
            app.TextBox.Value = filepath;            
        end

        % Button pushed function: Confirm
        function ConfirmPushed(app, event)
            filepath=app.TextBox.Value;
            Ztop=app.TopEditField.Value;
            Zbottom=app.BottomEditField.Value;
            
            updateBoundary(app.callapp,filepath,Ztop,Zbottom);  
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
    % Delete the dialog box 
           delete(app)             
        end

        % Button pushed function: Cancel
        function CancelButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '浏览';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '选择边界线文件';
            app.Confirm = uibutton(app.UIFigure, 'push');
            app.Confirm.ButtonPushedFcn = createCallbackFcn(app, @ConfirmPushed, true);
            app.Confirm.Text = '确定';
            app.Cancel = uibutton(app.UIFigure, 'push');
            app.Cancel.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.Cancel.Text = '取消';
            app.TextBox = uieditfield(app.UIFigure, 'text');
            app.TextBox.Value = 'E:\RZ_matlab\data\3Ddata\Boundary.txt';
            app.TopEditFieldLabel = uilabel(app.UIFigure);
            app.TopEditFieldLabel.Text = 'Top';
            app.TopEditField = uieditfield(app.UIFigure, 'numeric');
            app.BottomEditFieldLabel = uilabel(app.UIFigure);
            app.BottomEditFieldLabel.Text = 'Bottom';
            app.BottomEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SetBoundary(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end