classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        FileMenu          matlab.ui.container.Menu
        ImportFiles       matlab.ui.container.Menu
        points            matlab.ui.container.Menu
        lines             matlab.ui.container.Menu
        model_1           matlab.ui.container.Menu
        model_2           matlab.ui.container.Menu
        ExportFile        matlab.ui.container.Menu
        Menu              matlab.ui.container.Menu
        GridLayout        matlab.ui.container.GridLayout
        LeftPanel         matlab.ui.container.Panel
        Tree              matlab.ui.container.CheckBoxTree
        PointsNode        matlab.ui.container.TreeNode
        Lines_m1Node      matlab.ui.container.TreeNode
        Lines_m2Node      matlab.ui.container.TreeNode
        Label             matlab.ui.control.Label
        DropDown          matlab.ui.control.DropDown
        DeleteButton      matlab.ui.control.Button
        RightPanel        matlab.ui.container.Panel
        TabGroup          matlab.ui.container.TabGroup
        Resource          matlab.ui.container.Tab
        setsize           matlab.ui.control.Button
        ClearReView       matlab.ui.control.Button
        ResourcePlot      matlab.ui.control.UIAxes
        Field             matlab.ui.container.Tab
        Button            matlab.ui.control.Button
        FusionFunc        matlab.ui.control.DropDown
        Label_3           matlab.ui.control.Label
        PotentialFunc     matlab.ui.control.DropDown
        Label_2           matlab.ui.control.Label
        Caculate          matlab.ui.control.Button
        ClearFieldView    matlab.ui.control.Button
        FieldPlot         matlab.ui.control.UIAxes
        Surface           matlab.ui.container.Tab
        ClearSurfView     matlab.ui.control.Button
        ParseSurface      matlab.ui.control.Button
        setF              matlab.ui.control.NumericEditField
        Label_4           matlab.ui.control.Label
        SurfacePlot       matlab.ui.control.UIAxes
        Tab               matlab.ui.container.Tab
        ClearView         matlab.ui.control.Button
        setFF             matlab.ui.control.NumericEditField
        EditField_2Label  matlab.ui.control.Label
        ParseSU           matlab.ui.control.Button
        UncertPlot        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    %底层与文件结构、界面脱离
    %线两种模型在树上区分，线形区分
    %设置边界在计算之前
    %同时提取多个等值面
    %待插点超过一定范围分块计算
    %不用的变量清除
    %每个步骤的数据存储


    properties (Access = private)
        points_node
        lines_node
        Children
        Parent
       
    end
    
    properties (Access = public)  
        ScatterPlot
        ImportLinesApp
        ImportLines_2App
        ImportPointsApp
        LinePlot
        valueGrid;
        solution=[1,1,1];
        setSolution;
        setBoundary;
        signal=false;
        gridPoints;
        grid_X;
        grid_Y;
        grid_Z;
        fieldValue;
        total_P;
        Ztop;
        Zbottom;
        filteredPoints;
        filteredValues;
        filteredtotal_P;
        Boundary;
        D
        fileData;
    end
    
    
    methods (Access = private)
        
        function updatePlot(app, data, filename)
            hold(app.ResourcePlot,'on');              
            % 绘制数据点
            if app.DropDown.Value == "2D"
                app.ScatterPlot = scatter(app.ResourcePlot, data.X, data.Y, 3,'filled'); 
                scatter(app.FieldPlot, data.X, data.Y, 3,'filled'); 
                scatter(app.SurfacePlot, data.X, data.Y, 3,'filled'); 
            elseif app.DropDown.Value =="3D"
                app.ScatterPlot = scatter3(app.ResourcePlot, data.X, data.Y,data.Z, 3,'filled');              
            end
            app.ScatterPlot.Tag = filename;   
        end
        
        function updateLinePlot(app, data, filename)
            hold(app.ResourcePlot,'on');
            % 绘制数据点
            if app.DropDown.Value == "2D"
                app.LinePlot = plot(app.ResourcePlot, data.X, data.Y,'-'); 
                plot(app.FieldPlot, data.X, data.Y,'-'); 
                plot(app.SurfacePlot, data.X, data.Y,'-'); 
            elseif app.DropDown.Value =="3D"
                app.LinePlot = plot3(app.ResourcePlot, data.X, data.Y, data.Z, '-');              
            end
            app.LinePlot.Tag = filename;
        end
             
        function [pointsData,linesData,m2LinesData]=traverseTree(app,node,pointsData,linesData,m2LinesData)        
            % 获取当前节点的所有子节点
            childNodes = node.Children;
            
            % 遍历所有子节点
            for i = 1:numel(childNodes)
                childNode = childNodes(i);              
       
                if childNode.Tag=='p'  %点 
                    % Data=childNode.NodeData;
                    % PointsData(end+1).name= Data.name;
                    % PointsData(end).data= Data.data;
                    % PointsData(end).E= Data.E;
                    data=childNode.NodeData;
                    pointsData{end+1,1}=data;
                end
                    
                if childNode.Tag=='l' %线
                    % parentData = node.NodeData;
                    % LinesData(end+1).name = childNode.Text;
                    % LinesData(end).data = childNode.NodeData;
                    % LinesData(end).E=parentData.E;
                    % LinesData(end).Fdata=parentData.data;
                    data=childNode.NodeData;
                    linesData{end+1,1}=data;
                end

                if childNode.Tag=='ll'
                    data=childNode.NodeData;
                    m2LinesData{end+1,1}=data;
                end
             
                % 如果子节点还有子节点，递归调用自身
                if ~isempty(childNode.Children)
                    [pointsData,linesData]=traverseTree(app,childNode,pointsData,linesData,m2LinesData);
                end
            end
        end 


    end
    
    methods (Access = public)
       
        function updateLines(app,folderpath)
            %将导入线数据子界面的数据更新到主界面         

            if folderpath~=""  
                [~, foldername, ~] = fileparts(folderpath);     
                line_node = uitreenode(app.Lines_m1Node, 'Text', foldername,'Tag','L', 'Icon', '');   
                files = dir(fullfile(folderpath, '*.txt'));  % 获取文件夹中的所有文件
                for i = 1:length(files)
                    filename = files(i).name;  % 获取文件名
                    fileFullPath = fullfile(folderpath, filename);
                    data = readtable(fileFullPath);   %读取文件                                
                    updateLinePlot(app,data,filename)   %更新图窗
                    new_node = uitreenode(line_node, 'Text', filename, 'Tag','l', 'Icon', '','NodeData', fileFullPath);
       
                end
            end            
        end

        function updateLines_2(app,folderpath,filepath)
            %将导入线数据子界面的数据更新到主界面         

            if folderpath~=""&&filepath~=""  
                [~, foldername, ~] = fileparts(folderpath);
                data=readtable(filepath);
                Data.data=data;
                Data.name=foldername; 
                line_node = uitreenode(app.Lines_m2Node, 'Text', foldername,'Tag','L2','NodeData',Data, 'Icon', '');                
                files = dir(fullfile(folderpath, '*.txt'));  % 获取文件夹中的所有文件
                for i = 1:length(files)
                    filename = files(i).name;  % 获取文件名
                    fileFullPath = fullfile(folderpath, filename);
                    data = readtable(fileFullPath);   %读取文件                                
                    updateLinePlot(app,data,filename)   %更新图窗
                    new_node = uitreenode(line_node, 'Text', filename, 'Tag','ll', 'Icon', '','NodeData', fileFullPath);
%                     new_node.DeleteFcn = @(~,~) DeleteButtonPushed(app, new_node); % 设置删除回调函数                
                end
            end
        end

        
        function updateBoundary(app,filepath,Ztop,Zbottom)
            data = readtable(filepath);  % 读取文件数据 
            BX=data.X;
            BY=data.Y;
            BZ=data.Z;
            app.Boundary=[BX,BY,BZ];
            app.Ztop=Ztop;
            app.Zbottom=Zbottom;
            X=app.gridPoints(:,1);
            Y=app.gridPoints(:,2);
            Z=app.gridPoints(:,3);
            inPolygon = inpolygon(X,Y, BX,BY);
            filteredX=X(inPolygon&any(Z <= Ztop & Z >= Zbottom, 2));
            filteredY=Y(inPolygon&any(Z <= Ztop & Z >= Zbottom, 2));
            filteredZ=Z(inPolygon&any(Z <= Ztop & Z >= Zbottom, 2));
            app.filteredPoints = [filteredX,filteredY,filteredZ];
            app.filteredValues=app.fieldValue(inPolygon & any(Z <= Ztop & Z >= Zbottom, 2));
            app.filteredtotal_P=app.total_P(inPolygon & any(Z <= Ztop & Z >= Zbottom, 2));

            delete(app.FieldPlot.Children);
            colorbar(app.FieldPlot,'off');
            updateFieldView(app,app.filteredPoints,app.filteredValues,app.D) 
            hold(app.FieldPlot,'on');  
            plot3(app.FieldPlot,BX,BY,BZ);

        end
     
        function bool=SetSolution(app,solution_x,solution_y,solution_z)
            %设置网格大小子界面更新到主界面
            bool=true;
            if(solution_x==0||solution_y==0||solution_z==0)
                bool=false;
                return;
            end
            try
                app.solution(1,1)=solution_x;
                app.solution(1,2)=solution_y;
                app.solution(1,3)=solution_z;
            catch
                bool=false;
                return;
            end
            return;
        end
        
        function setSignal(app,signal)
            app.signal=signal;
        end

        function updateFieldView(app,gridPoints,fieldValue,D)
            cla(app.FieldPlot);
            %更新数据势场图窗
            if D==3
                scatter3(app.FieldPlot,gridPoints(:,1),gridPoints(:,2),gridPoints(:,3),25,fieldValue(:),'filled');
            elseif D==2
                scatter(app.FieldPlot,gridPoints(:,1),gridPoints(:,2),10,fieldValue(:),'filled');
            end
            colormap(app.FieldPlot,"parula");
            colorbar(app.FieldPlot);
            hold(app.FieldPlot,"on");
        end
       
        function updateUncertView(app,gridPoints,total_P,D)
            %更新不确定性分布图窗
            if D==3
                scatter3(app.UncertPlot,gridPoints(:,1),gridPoints(:,2),gridPoints(:,3),25,total_P(:));
            elseif D==2
                scatter(app.UncertPlot,gridPoints(:,1),gridPoints(:,2),10,total_P(:));
            end 
            colorbar(app.UncertPlot);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {505, 505};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            %维度选项改变
            PNodes=app.PointsNode.Children;
            delete(PNodes);
            LNodes=app.Lines_m1Node.Children;
            delete(LNodes);
            delete(app.ResourcePlot.Children);
            delete(app.FieldPlot.Children);
            colorbar(app.FieldPlot,'off');
            delete(app.SurfacePlot.Children);
            colorbar(app.SurfacePlot,'off');
            delete(app.UncertPlot.Children);
            colorbar(app.UncertPlot,'off');
            if app.DropDown.Value=="3D"
                app.D=3;
            else
                app.D=2;
            end
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            Nodes = app.Tree.SelectedNodes; 
            % 判断节点是否存在
            if ~isempty(Nodes)
               % 获取要删除的节点的名称
                identifier = Nodes(1).Text;        
                % 获取当前图形轴上的所有对象
                objects = app.ResourcePlot.Children;
                if ~isempty(objects)
                    % 遍历图形对象，找到属于选中节点的图形对象，并删除它们
                    for i = 1:numel(objects)
                        if strcmp(objects(i).Tag, identifier)
                            delete(objects(i));
                        end
                    end
                end
            end
        
          % 删除节点
            delete(Nodes);

            
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            %选定节点

            Nodes = app.Tree.SelectedNodes; 
            if ~isempty(Nodes.Children)
                return
            end
            
            if numel(Nodes)==1
                Nodes={Nodes};
            end
            
            for i = 1:numel(Nodes)
                Node = Nodes{i};
                filename = Node.Text;
                fileFullPath = Node.NodeData;
                data=readtable(fileFullPath);
                
                if Node.Tag=='p'
                   
                    updatePlot(app, data, filename)
                elseif Node.Tag=='l'
                    updateLinePlot(app,data,filename)
                else
                    return
                end                            
            end
        end

        % Menu selected function: points
        function ImportPointsFiles(app, event)
            %导入点数据文件
            if app.DropDown.Value=="2D"
                app.D=2;
            elseif app.DropDown.Value=="3D"
                app.D=3;
            end
            % app.ImportPointsApp=ImportPoints(app,app.D);
            % waitfor(app.ImportPointsApp); 
            [filename, path] = uigetfile({'*.txt'; '*.csv'}, '选择要导入的文件');
            if isequal(filename, 0)  % 用户取消选择
                return;
            end
            fullPath=[path,filename];
            
            %将导入点数据子界面的数据更新到主界面               
            data = readtable(fullPath);  % 读取文件数据 
            updatePlot(app, data, filename);
            uitreenode(app.PointsNode,'Text', filename,'Tag','p','NodeData',fullPath);
            
        end

        % Button pushed function: Caculate
        function CaculatePushed(app, event)
            %开始计算按钮
            pointsData=[];
            linesData=[];
            m2LinesData=[];
            [pointsData,~,~]=traverseTree(app,app.PointsNode,pointsData,linesData,m2LinesData);
            [~,linesData,~]=traverseTree(app,app.Lines_m1Node,pointsData,linesData,m2LinesData);
            [~,~, m2LinesData]=traverseTree(app,app.Lines_m1Node,pointsData,linesData,m2LinesData);
            typeNum0=zeros(size(pointsData,1),1);
            typeNum1=ones(size(linesData,1),1);
            typeNum2=repmat(2,size(m2LinesData,1),1);
            app.fileData=[num2cell(typeNum0), pointsData;
                                   num2cell(typeNum1), linesData;
                                   num2cell(typeNum2), m2LinesData];

            s=app.PotentialFunc.Value;
            switch s
                case 'General'
                    s='s';                
                case 'Gaussian'
                    s='g';
            end         
           
            if app.DropDown.Value=="3D"
                app.D=3;
            else
                app.D=2;
            end

            u=2;
            f = app.FusionFunc.Value;
            [limit,~,~] = GetDataLimit(app.fileData, app.D);  
            if app.D==3
                [app.grid_X,app.grid_Y,app.grid_Z] = meshgrid(limit(1,1):app.solution(1,1):limit(2,1),limit(1,2):app.solution(1,2):limit(2,2),limit(1,3):app.solution(1,3):limit(2,3));
                app.gridPoints=[app.grid_X(:), app.grid_Y(:), app.grid_Z(:)]; 
            elseif app.D==2
                [app.grid_X,app.grid_Y] = meshgrid(limit(1,1):app.solution(1,1):limit(2,1),limit(1,2):app.solution(1,2):limit(2,2));
                app.gridPoints=[app.grid_X(:),app.grid_Y(:)]; 
            end
            % app.gridPoints = GetGridPoints(limit,app.solution,app.D);
            % [P, F] = GetPFData(app.gridPoints, u, s, PointsData, LinesData);
            [app.fieldValue,app.total_P]=GetFieldValue( app.gridPoints, app.fileData, u, s, f);
            app.signal=false;
            updateFieldView(app,app.gridPoints,app.fieldValue, app.D);    
            % updateUncertView(app,app.gridPoints,app.total_P, app.D);
        end

        % Menu selected function: model_2
        function ImportLinesFoder_m2(app, event)
           % 导入线数据
           folderpath=uigetdir('选择文件夹');  
           if isequal(folderpath, 0)  % 用户取消选择
                return;
            end
            [~, foldername, ~] = fileparts(folderpath);     
            line_node = uitreenode(app.Lines_m1Node, 'Text', foldername,'Tag','L', 'Icon', '');   
            files = dir(fullfile(folderpath, '*.txt'));  % 获取文件夹中的所有文件
            for i = 1:length(files)
                filename = files(i).name;  % 获取文件名
                fileFullPath = fullfile(folderpath, filename);
                data = readtable(fileFullPath);   %读取文件                                
                updateLinePlot(app,data,filename)   %更新图窗
                uitreenode(line_node, 'Text', filename, 'Tag','ll', 'Icon', '','NodeData', fileFullPath);
            end

        end

        % Menu selected function: model_1
        function ImportLinesFoder_m1(app, event)
           % 导入线数据
           folderpath=uigetdir('选择文件夹');  
           if isequal(folderpath, 0)  % 用户取消选择
                return;
            end
            [~, foldername, ~] = fileparts(folderpath);     
            line_node = uitreenode(app.Lines_m1Node, 'Text', foldername,'Tag','L', 'Icon', '');   
            files = dir(fullfile(folderpath, '*.txt'));  % 获取文件夹中的所有文件
            for i = 1:length(files)
                filename = files(i).name;  % 获取文件名
                fileFullPath = fullfile(folderpath, filename);
                data = readtable(fileFullPath);   %读取文件                                
                updateLinePlot(app,data,filename)   %更新图窗
                uitreenode(line_node, 'Text', filename, 'Tag','l', 'Icon', '','NodeData', fileFullPath);
            end

        end

        % Menu selected function: ExportFile
        function ExportFileMenuSelected(app, event)
            filter ={'*.csv';'*.xls';'*.xlsx';'*.txt'};
            [file,path] = uiputfile(filter);
            display(fullfile(path,file));
            if isequal(file,0)||isequal(path,0)
                return;
            else               
                valueMartix=[app.gridPoints,app.fieldValue,app.total_P];    
                path=strcat(path,file);
                writematrix(valueMartix,path)
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
           delete(app)
        end

        % Button pushed function: ClearFieldView
        function ClearFieldViewButtonPushed(app, event)
            delete(app.FieldPlot.Children);
            colorbar(app.FieldPlot,'off');
            
        end

        % Button pushed function: ClearReView
        function ClearReViewButtonPushed(app, event)
             delete(app.ResourcePlot.Children);
        end

        % Button pushed function: setsize
        function setsizePushed(app, event)
            %设置网格大小按钮
  
            app.setSolution=SelectSolution(app,app.solution(1),app.solution(2),app.solution(3), app.D);
            waitfor(app.setSolution);
            if(~app.signal)
                return;
            end
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
           %选中树节点复选框
            Nodes = event.LeafCheckedNodes;
            for i = 1:numel(Nodes)
                Node = Nodes(i);  
                if Node.Tag=='p'
                    filename = Node.Text;
                    fileFullPath = Node.NodeData;
                    Data=readtable(fileFullPath);
                    updatePlot(app, Data, filename)

                elseif Node.Tag=='l'
                    filename = Node.Text;
                    fileFullPath = Node.NodeData;
                    Data=readtable(fileFullPath);
                    updateLinePlot(app,Data,filename)

                elseif Node.Tag== 'P'
                    childNodes=Node.Children;
                    if  ~isempty(childNodes)
                 
                        for j= 1:numel(childNodes)
                            Node = childNodes(j);                      
                            filename = Node.Text;
                            fileFullPath = Node.NodeData;
                            Data=readtable(fileFullPath);
                            updatePlot(app, Data, filename)
                        end
                    end
                    
                elseif Node.Tag== 'L'
                    childNodes=Node.Children;
                    if  ~isempty(childNodes)
                        for j = 1:numel(childNodes)
                            Node = childNodes(j);  
                            filename = Node.Text;
                            fileFullPath = Node.NodeData;
                            Data=readtable(fileFullPath);
                            updateLinePlot(app,Data,filename)                                                 
                        end
                    end

                else
                    return
                end                            
            end
        end

        % Button pushed function: ParseSurface
        function ParseSurfaceButtonPushed(app, event)
            F=app.setF.Value;
            if app.DropDown.Value=="3D"
                [m,n,p]=size(app.grid_X);
                V=reshape(app.fieldValue,[m,n,p]);
                %isosurface(app.grid_X,app.grid_Y,app.grid_Z,V,F);     
                pic = patch(app.SurfacePlot,isosurface(app.grid_X,app.grid_Y,app.grid_Z,V,F));
                isonormals(app.grid_X,app.grid_Y,app.grid_Z,V,pic)
                set(pic,'FaceColor',[rand(),rand(),rand()],'EdgeColor','none');
                hold(app.SurfacePlot, "on")
               
            elseif app.DropDown.Value=="2D"
                [m,n]=size(app.grid_X);
                V=reshape(app.fieldValue,[m,n]);
                contour(app.SurfacePlot,app.grid_X,app.grid_Y,V,[F,F],'ShowText','on'); 
                hold(app.SurfacePlot,"on");

            end
            
        end

        % Button pushed function: ClearSurfView
        function ClearSurfViewButtonPushed(app, event)
            delete(app.SurfacePlot.Children);
            colorbar(app.SurfacePlot,'off');
        end

        % Button pushed function: ClearView
        function ClearViewButtonPushed(app, event)
            delete(app.UncertPlot.Children);
            colorbar(app.UncertPlot,'off');
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.setBoundary=SetBoundary(app);
            waitfor(app.setSolution);

        end

        % Button pushed function: ParseSU
        function ParseSUButtonPushed(app, event)
            F=app.setFF.Value;
            if app.DropDown.Value=="3D"
                [m,n,p]=size(app.grid_X);
                V=reshape(app.fieldValue,[m,n,p]);
                COLORS=reshape(app.total_P,[m,n,p]);
                [faces,verts,colors]=isosurface(app.grid_X,app.grid_Y,app.grid_Z,V,F,COLORS);    
                in=inpolygon(verts(:,1),verts(:,2),app.Boundary(:,1),app.Boundary(:,2));
                inverts=verts(in,:);
                pic = patch(app.UncertPlot,'Vertices',inverts,'Faces',faces);
                isonormals(app.grid_X,app.grid_Y,app.grid_Z,V,pic)
                set(pic,'FaceVertexCData',colors,'FaceColor',[rand(),rand(),rand()],'EdgeColor','none');
                hold(app.UncertPlot, "on")
               
            elseif app.DropDown.Value=="2D"
                [m,n]=size(app.grid_X);
                V=reshape(app.fieldValue,[m,n]);
                % P=reshape(app.total_P,[m,n]);
 
                [M,c]=contour(app.UncertPlot,app.grid_X,app.grid_Y,V,[F,F],'ShowText','on');

                % contourColor = P; % 使用属性值P作为颜色数据
                [Points] = M2Points(M);
                PointsData=struct();
                LinesData=struct();
                [PointsData,~]=traverseTree(app,app.PointsNode,PointsData,LinesData);
                [~,LinesData]=traverseTree(app,app.Lines_m1Node,PointsData,LinesData);
                PointsData(1)=[];
                LinesData(1)=[];

                s=app.PotentialFunc.Value;
                switch s
                    case 'General'
                        s='s';                
                    case 'Gaussian'
                        s='g';
                end          
                u=2;
                f = app.FusionFunc.Value;

                [P, F] = GetPFData(Points, u, s, PointsData, LinesData);
                [~, tP]=GetFieldValue(P, F, f);
                
                % c.LineColor = 'interp'; % 使用插值方法设置线条颜色
                % c.LineColor = contourColor; % 使用属性值P设置线条颜色
                hold(app.UncertPlot,"on");
                scatter(app.UncertPlot,Points(:,1),Points(:,2),3,tP(:));
                colormap(app.UncertPlot,'jet'); % 设置颜色映射
                colorbar(app.UncertPlot); % 显示颜色条                 
            end


        end

        % Menu selected function: Menu
        function MenuSelected(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = '文件';
            app.ImportFiles = uimenu(app.FileMenu);
            app.ImportFiles.Text = '导入场源数据';
            app.points = uimenu(app.ImportFiles);
            app.points.MenuSelectedFcn = createCallbackFcn(app, @ImportPointsFiles, true);
            app.points.Text = '点';
            app.lines = uimenu(app.ImportFiles);
            app.lines.Text = '线';
            app.model_1 = uimenu(app.lines);
            app.model_1.MenuSelectedFcn = createCallbackFcn(app, @ImportLinesFoder_m1, true);
            app.model_1.Text = '“ε-带”模型';
            app.model_2 = uimenu(app.lines);
            app.model_2.MenuSelectedFcn = createCallbackFcn(app, @ImportLinesFoder_m2, true);
            app.model_2.Text = '“ε_σ-带”模型';
            app.ExportFile = uimenu(app.FileMenu);
            app.ExportFile.MenuSelectedFcn = createCallbackFcn(app, @ExportFileMenuSelected, true);
            app.ExportFile.Text = '导出数据势场';
            app.Menu = uimenu(app.FileMenu);
            app.Menu.MenuSelectedFcn = createCallbackFcn(app, @MenuSelected, true);
            app.Menu.Text = '导出等值面(线)';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DeleteButton = uibutton(app.LeftPanel, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = '删除选定对象';
            app.DropDown = uidropdown(app.LeftPanel);
            app.DropDown.Items = {'3D', '2D'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Tooltip = {'导入不同维度文件将导致计算错误。'};
            app.DropDown.Value = '3D';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '选择维度';
            app.Tree = uitree(app.LeftPanel, 'checkbox');
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Tree.Editable = 'on';
            app.PointsNode = uitreenode(app.Tree);
            app.PointsNode.Tag = '‘P’';
            app.PointsNode.Text = 'Points';
            app.Lines_m1Node = uitreenode(app.Tree);
            app.Lines_m1Node.Tag = '''L''';
            app.Lines_m1Node.Text = 'Lines_m1';
            app.Lines_m2Node = uitreenode(app.Tree);
            app.Lines_m2Node.Tag = '''L''';
            app.Lines_m2Node.Text = 'Lines_m2';
            % Assign Checked Nodes
            app.Tree.CheckedNodes = [app.PointsNode];
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.Resource = uitab(app.TabGroup);
            app.Resource.Title = '场源对象视图';
            app.ResourcePlot = uiaxes(app.Resource);
            title(app.ResourcePlot, '场源对象视图')
            xlabel(app.ResourcePlot, 'X')
            ylabel(app.ResourcePlot, 'Y')
            app.ClearReView = uibutton(app.Resource, 'push');
            app.ClearReView.ButtonPushedFcn = createCallbackFcn(app, @ClearReViewButtonPushed, true);
            app.ClearReView.Text = '清空场源视图';
            app.setsize = uibutton(app.Resource, 'push');
            app.setsize.ButtonPushedFcn = createCallbackFcn(app, @setsizePushed, true);
            app.setsize.Text = '设置网格大小';
            app.Field = uitab(app.TabGroup);
            app.Field.Title = '数据势场';
            app.FieldPlot = uiaxes(app.Field);
            title(app.FieldPlot, '数据势场视图')
            xlabel(app.FieldPlot, 'X')
            ylabel(app.FieldPlot, 'Y')
            zlabel(app.FieldPlot, 'Z')
            app.ClearFieldView = uibutton(app.Field, 'push');
            app.ClearFieldView.ButtonPushedFcn = createCallbackFcn(app, @ClearFieldViewButtonPushed, true);
            app.ClearFieldView.Text = '清空势场视图';
            app.Caculate = uibutton(app.Field, 'push');
            app.Caculate.ButtonPushedFcn = createCallbackFcn(app, @CaculatePushed, true);
            app.Caculate.Text = '开始计算';
            app.Label_2 = uilabel(app.Field);
            app.Label_2.Text = '选择势函数';
            app.PotentialFunc = uidropdown(app.Field);
            app.PotentialFunc.Items = {'General', 'Gaussian'};
            app.PotentialFunc.Tooltip = {''};
            app.PotentialFunc.Value = 'General';
            app.Label_3 = uilabel(app.Field);
            app.Label_3.Text = '选择融合方法';
            app.FusionFunc = uidropdown(app.Field);
            app.FusionFunc.Items = {'Add', 'ExpAdd'};
            app.FusionFunc.Tooltip = {''};
            app.FusionFunc.Value = 'Add';
            app.Button = uibutton(app.Field, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '设置边界';
            app.Surface = uitab(app.TabGroup);
            app.Surface.Title = '等值面(线)';
            app.SurfacePlot = uiaxes(app.Surface);
            title(app.SurfacePlot, '等值面(线)视图')
            xlabel(app.SurfacePlot, 'X')
            ylabel(app.SurfacePlot, 'Y')
            zlabel(app.SurfacePlot, 'Z')
            app.Label_4 = uilabel(app.Surface);
            app.Label_4.Text = '设置属性值';
            app.setF = uieditfield(app.Surface, 'numeric');
            app.ParseSurface = uibutton(app.Surface, 'push');
            app.ParseSurface.ButtonPushedFcn = createCallbackFcn(app, @ParseSurfaceButtonPushed, true);
            app.ParseSurface.Text = '开始提取';
            app.ClearSurfView = uibutton(app.Surface, 'push');
            app.ClearSurfView.ButtonPushedFcn = createCallbackFcn(app, @ClearSurfViewButtonPushed, true);
            app.ClearSurfView.Text = '清空视图';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '可靠性指数';
            app.UncertPlot = uiaxes(app.Tab);
            title(app.UncertPlot, '可靠性指数分布')
            xlabel(app.UncertPlot, 'X')
            ylabel(app.UncertPlot, 'Y')
            app.ParseSU = uibutton(app.Tab, 'push');
            app.ParseSU.ButtonPushedFcn = createCallbackFcn(app, @ParseSUButtonPushed, true);
            app.ParseSU.Text = '开始提取';
            app.EditField_2Label = uilabel(app.Tab);
            app.EditField_2Label.Text = '设置属性值';
            app.setFF = uieditfield(app.Tab, 'numeric');
            app.ClearView = uibutton(app.Tab, 'push');
            app.ClearView.ButtonPushedFcn = createCallbackFcn(app, @ClearViewButtonPushed, true);
            app.ClearView.Text = '清空视图';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end