classdef ImportLines < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        EditField_9  matlab.ui.control.NumericEditField
        EditField_8  matlab.ui.control.NumericEditField
        EditField_7  matlab.ui.control.NumericEditField
        EditField_6  matlab.ui.control.NumericEditField
        EditField_5  matlab.ui.control.NumericEditField
        EditField_4  matlab.ui.control.NumericEditField
        EditField_3  matlab.ui.control.NumericEditField
        EditField_2  matlab.ui.control.NumericEditField
        EditField    matlab.ui.control.NumericEditField
        Label_3      matlab.ui.control.Label
        TextBox      matlab.ui.control.EditField
        TextBox_2    matlab.ui.control.EditField
        Button_2     matlab.ui.control.Button
        Cancel       matlab.ui.control.Button
        Label_2      matlab.ui.control.Label
        Confirm      matlab.ui.control.Button
        Label        matlab.ui.control.Label
        Button       matlab.ui.control.Button
    end

    
    properties (Access = public)
        callapp % mainapp
        
    end
    
    properties (Access = private)
        D % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, D)
            app.callapp=mainapp;  
            app.D=[D];
            if D==2
                app.EditField_3.Enable="off";
                app.EditField_6.Enable="off";
                app.EditField_7.Enable="off";
                app.EditField_8.Enable="off";
                app.EditField_9.Enable="off";

            elseif D==3
                app.EditField_3.Enable="on";
                app.EditField_6.Enable="on";
                app.EditField_7.Enable="on";
                app.EditField_8.Enable="on";
                app.EditField_9.Enable="on";
            end                 
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            folder = uigetdir();       
            app.TextBox.Value = folder;            
        end

        % Button pushed function: Confirm
        function ConfirmPushed(app, event)
            folder=app.TextBox.Value;
            filepath=app.TextBox_2.Value;
             if app.D==2
                 E=[app.EditField.Value,app.EditField_2.Value;
                      app.EditField_4.Value,app.EditField_5.Value];
             elseif app.D==3
                 E=[app.EditField.Value,app.EditField_2.Value,app.EditField_3.Value;
                      app.EditField_4.Value,app.EditField_5.Value,app.EditField_6.Value;
                      app.EditField_7.Value,app.EditField_8.Value,app.EditField_9.Value];
             end
            updateLines(app.callapp,folder,filepath,E);  
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            delete(app);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            [file,path] = uigetfile({'*.txt'; '*.csv';'*.xlsx';'*.xls'}, '选择要导入的文件');
            app.TextBox_2.Value = [path,file];
        end

        % Button pushed function: Cancel
        function CancelButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '浏览';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '选择文件夹';
            app.Confirm = uibutton(app.UIFigure, 'push');
            app.Confirm.ButtonPushedFcn = createCallbackFcn(app, @ConfirmPushed, true);
            app.Confirm.Text = '确定';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '属性值文件';
            app.Cancel = uibutton(app.UIFigure, 'push');
            app.Cancel.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.Cancel.Text = '取消';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '浏览';
            app.TextBox_2 = uieditfield(app.UIFigure, 'text');
            app.TextBox_2.Value = 'E:\RZ_matlab\data\2Ddata\m1属性值表.xlsx';
            app.TextBox = uieditfield(app.UIFigure, 'text');
            app.TextBox.Value = 'E:\RZ_matlab\data\2Ddata\Lines';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '带宽矩阵';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_8 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_9 = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImportLines(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end